classdef classBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Class_Browser        matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        RemoveCometButton    matlab.ui.control.Button
        Image                matlab.ui.control.Image
        RenameClassButton    matlab.ui.control.Button
        RemoveCometsButton   matlab.ui.control.Button
        JumptoCometButton    matlab.ui.control.Button
        ClassesListBox       matlab.ui.control.ListBox
        ClassesListBoxLabel  matlab.ui.control.Label
        ClassMembersPanel    matlab.ui.container.Panel
        ClassMemberAxes      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        classCatalog % This contains the images, maps and other infos of comets
        selectedObjects = []; % Container of selected comets
        mainApp % Description
    end
    
    properties (Access = public)
        isStart = 1; % Description
    end
    
    methods (Access = public)
        
        function recalcCatalog(app, isStart)
            axesWidth = app.ClassMemberAxes.Position(3);
            app.classCatalog = composeClassImage(app.mainApp.comet_handles.Classes, app.mainApp.comet_handles.Imgs_Stretched, app.mainApp.comet_handles.ImgsNames, axesWidth);
            

            app.ClassMemberAxes.Position(4) = size(app.classCatalog{1,2}(:,:,1),1);
            if size(app.classCatalog{1,2}(:,:,1),1) < app.ClassMembersPanel.Position(4)
                app.ClassMemberAxes.Position(2) = app.ClassMembersPanel.Position(4) - size(app.classCatalog{1,2}(:,:,1),1);
            else
                app.ClassMemberAxes.Position(2) = 5;
            end
            if isStart == 1
                app.ClassesListBox.Items = app.classCatalog(:,1);
                app.ClassesListBox.Value = app.classCatalog{1,1};
                app.ClassMemberAxes.XLim = [0 axesWidth];
                imshow(app.classCatalog{1,2}(:,:,1),'Parent',app.ClassMemberAxes);
            else
                for i = 1:length(app.selectedObjects)
                    delete(app.selectedObjects(i).hRec);
                end
                app.ClassesListBox.Items = app.classCatalog(:,1);
                classIx = strcmp(app.classCatalog(:,1),app.ClassesListBox.Value);
                app.ClassMemberAxes.Children.CData = app.classCatalog{classIx,2}(:,:,1);
            end
            app.Image.ImageSource = app.mainApp.CometIcon;
            app.selectedObjects = [];
        end
    
        function refreshCatalogPlot(app)
            classIx = strcmp(app.classCatalog(:,1),app.ClassesListBox.Value);
            app.ClassMemberAxes.Position(4) = size(app.classCatalog{classIx,2}(:,:,1),1);
            if size(app.classCatalog{classIx,2}(:,:,1),1) < app.ClassMembersPanel.Position(4)
                app.ClassMemberAxes.Position(2) = app.ClassMembersPanel.Position(4) - size(app.classCatalog{classIx,2}(:,:,1),1);
            else
                app.ClassMemberAxes.Position(2) = 5;
            end
            app.ClassMemberAxes.Children.CData = app.classCatalog{classIx,2}(:,:,1);
            scroll(app.ClassMembersPanel,1,1)
            scroll(app.ClassMembersPanel,'top')
        end
        
        function addClassToCatalog(app, className)
            [hi, ~] = size(app.classCatalog);
            [~, mapWidth, ~] = size(app.classCatalog{1,2});
            app.classCatalog{hi+1, 1} = className;
            app.classCatalog{hi+1, 2} = uint8(cat(3,240*ones(25, mapWidth), zeros(25, mapWidth)));
            app.ClassesListBox.Items = app.classCatalog(:,1);
        end
        
        function removeClassFromCatalog(app, className)
            idx = contains(app.classCatalog(:,1), className);
            app.classCatalog(idx,:) = [];
            app.ClassesListBox.Items = app.classCatalog(:,1);
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.Panel.Enable = 'off';
            app.mainApp = mainApp;
            recalcCatalog(app, app.isStart)
            app.ClassMemberAxes.XTickLabel = [];
            app.ClassMemberAxes.YTickLabel = [];
            app.ClassMemberAxes.XAxis.Visible = 'off';
            app.ClassMemberAxes.YAxis.Visible = 'off';
            app.ClassMemberAxes.Toolbar.Visible = "off";
            disableDefaultInteractivity(app.ClassMemberAxes)
            refreshCatalogPlot(app)
            app.isStart = 0;
            scroll(app.ClassMembersPanel,'top')
            app.Panel.Enable = 'on';
        end

        % Value changed function: ClassesListBox
        function ClassesListBoxValueChanged(app, event)
            app.Panel.Enable = 'off';
            value = find(strcmp(app.ClassesListBox.Items,app.ClassesListBox.Value));
            if size(app.classCatalog{value,2},1) < app.ClassMembersPanel.Position(4)
                app.ClassMemberAxes.Position(2) = app.ClassMembersPanel.Position(4) - size(app.classCatalog{value,2},1);
            else
                app.ClassMemberAxes.Position(2) = 5;
            end
            app.ClassMemberAxes.Position(4) = size(app.classCatalog{value,2},1);
            app.ClassMemberAxes.Children(end).CData = app.classCatalog{value,2};
            for i = 1:length(app.selectedObjects)
                delete(app.selectedObjects(i).hRec)
            end
            app.mainApp.selectedComet = [];
            app.selectedObjects = [];
            app.Image.ImageSource = app.mainApp.CometIcon;
            scroll(app.ClassMembersPanel,'top')
            app.Panel.Enable = 'on';
        end

        % Window button down function: Class_Browser
        function Class_BrowserWindowButtonDown(app, event)
            cp = app.ClassMemberAxes.CurrentPoint;
            x = round(cp(1, 1));
            y = round(cp(1, 2)) + 20;
            if x>0 && y>0 && x<app.ClassMemberAxes.Position(3) && y<app.ClassMemberAxes.Position(4) %%%%%% if hit on axes
                value = find(strcmp(app.ClassesListBox.Items,app.ClassesListBox.Value));
                catalogImSize = size(app.classCatalog{value,2},1);
                lw = 4;
                thumbnailSize = 94;
                if y<catalogImSize %%%%%% if hit within catalog size
                    idx = app.classCatalog{value,4}(y,x);
                    if idx>0  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% if hit on valid thumbnail
                        app.mainApp.selectedComet.param = app.mainApp.comet_handles.Classes.(app.ClassesListBox.Value).Members(idx);
                        [hi, width] = size(app.classCatalog{value,3});
                        [row,col] = ind2sub([width hi],idx);
                        cometInfo = app.classCatalog{value,3}{col,row};
                        [imCoorX, imCoorY] = find(app.classCatalog{value,4} == idx);

                        drawX = min(imCoorY);
                        drawY = min(imCoorX);

                        if strcmp(app.Class_Browser.SelectionType,'normal') || isempty(app.selectedObjects) %%%%%%%%%%%%%%%% if normal selection
                            if numel(app.selectedObjects) > 0 && ~isempty(app.selectedObjects(1).CellNumber)
                                for i = 1:length(app.selectedObjects)                      %
                                    delete(app.selectedObjects(i).hRec)                    % remove previously selected
                                end                                                        %
                                app.selectedObjects = [];                                  %
                            end                                                            %
                            app.selectedObjects.CellNumber = cometInfo.CellNumber;
                            app.selectedObjects.ImName = cometInfo.ImName;
                            app.selectedObjects.cometID = cometInfo.cometID;
                            app.selectedObjects.hRec = rectangle('Position',[drawX, drawY, thumbnailSize, thumbnailSize],'EdgeColor', 'red', 'LineWidth',lw, 'Parent', app.ClassMemberAxes);
                            IndImgShown = strcmp(app.mainApp.comet_handles.ImgsNames,app.selectedObjects.ImName);
                            [xCoor,yCoor] = find(app.mainApp.comet_handles.Imgs_Stretched(:,:,2,IndImgShown)==app.selectedObjects.cometID);
                            xMin = min(xCoor);
                            xMax = max(xCoor);
                            yMin = min(yCoor);
                            yMax = max(yCoor);
                            app.Image.ImageSource = composeImage(app.mainApp.comet_handles.Imgs_Stretched(xMin:xMax,yMin:yMax,:,IndImgShown));
                        else     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% if multy selection
                            app.Image.ImageSource = app.mainApp.CometIcon;
                            app.mainApp.selectedComet = [];
                            if isempty(app.selectedObjects)
                                app.selectedObjects.CellNumber = cometInfo.CellNumber;
                                app.selectedObjects.ImName = cometInfo.ImName;
                                app.selectedObjects.hRec = rectangle('Position',[drawX, drawY, thumbnailSize, thumbnailSize],'EdgeColor', 'red', 'LineWidth',lw, 'Parent', app.ClassMemberAxes);
                                IndImgShown = strcmp(app.mainApp.ImgsNames,app.selectedObjects.ImName);
                                coor = app.mainApp.comet_handles.Classes.(app.ClassesListBox.Value).Members(idx).thumbnailCoor;
                                app.Image.ImageSource = composeImage(app.mainApp.comet_handles.Imgs_Stretched(coor(1,1):coor(2,1),coor(2,2):coor(1,2),1:3,IndImgShown));
                            else
                                selected = [app.selectedObjects.CellNumber] == idx;
                                if any(selected)
                                    delete(app.selectedObjects(selected).hRec);
                                    app.selectedObjects(selected).CellNumber = 0;
                                    app.selectedObjects(selected).ImName = 0;
                                else
                                    numOfSelected = length(app.selectedObjects);
                                    app.selectedObjects(numOfSelected+1).CellNumber = cometInfo.CellNumber;
                                    app.selectedObjects(numOfSelected+1).ImName = cometInfo.ImName;
                                    app.selectedObjects(numOfSelected+1).hRec = rectangle('Position',[drawX, drawY, thumbnailSize, thumbnailSize],'EdgeColor', 'red', 'LineWidth',lw, 'Parent', app.ClassMemberAxes);
                                end
                            end
                        end
                    end
                elseif strcmp(app.Class_Browser.SelectionType,'normal')
                    if ~isempty(app.selectedObjects)
                        for i = 1:length(app.selectedObjects)
                            delete(app.selectedObjects(i).hRec)
                        end
                        app.selectedObjects = [];
                    end
                    app.Image.ImageSource = app.mainApp.CometIcon;
                end
            end
        end

        % Button pushed function: JumptoCometButton
        function JumptoCometButtonPushed(app, event)
            if ~isempty(app.mainApp.selectedComet)
                ImName = app.mainApp.selectedComet.param.ImName;
                imID = find(strcmp(ImName,app.mainApp.comet_handles.ImgsNames));
                app.mainApp.comet_handles.IndImgShown = imID;
                NumImages = app.mainApp.comet_handles.NumImages;
                [xCoor,yCoor] = find(app.mainApp.comet_handles.Imgs_Stretched(:,:,2,imID)==app.selectedObjects.cometID);
                xMin = min(xCoor);
                xMax = max(xCoor);
                yMin = min(yCoor);
                yMax = max(yCoor);
                centerPoint = round([mean([xMin,xMax]), mean([yMin,yMax])]);
                idx = length(app.mainApp.axes1.Children);
                if idx > 1
                    delete(app.mainApp.axes1.Children(1))
                end
                set(app.mainApp.text_Num,'Text', ['Image: ' num2str(imID) '/' num2str(NumImages)]);
                set(app.mainApp.text_Name,'Text', ['Image: ' char(app.mainApp.comet_handles.ImgsNames{imID})]);
                app.mainApp.axes1.Children.CData = composeImage(app.mainApp.comet_handles.Imgs_Stretched(:,:,:,imID));
                app.mainApp.figure1.Visible = 'off';
                app.mainApp.figure1.Visible = 'on';
                if centerPoint(2) > app.mainApp.comet_handles.ImageSize(2)/2
                    xSpecing = -20 - 12 * length(app.ClassesListBox.Value);
                else
                    xSpecing = 30;
                end
                if centerPoint(1) > app.mainApp.comet_handles.ImageSize(1)/2
                    ySpecing = -30;
                else
                    ySpecing = 30;
                end
                app.mainApp.imDatatipText = text(app.mainApp.axes1,centerPoint(2)+xSpecing,centerPoint(1)+ySpecing,app.ClassesListBox.Value,...
                    'Color','red','FontSize',14, 'FontWeight', 'bold', 'Interpreter', 'none');
                app.mainApp.imDatatipText.BackgroundColor = [1 1 1];
                app.mainApp.delay = now;
                app.mainApp.selectedComet = [];
            end
        end

        % Close request function: Class_Browser
        function Class_BrowserCloseRequest(app, event)
            app.mainApp.classBrowser_handle = [];
            delete(app)
        end

        % Button pushed function: RemoveCometButton
        function RemoveCometButtonPushed(app, event)
            if isfield(app.selectedObjects,'CellNumber') && ~isempty(app.selectedObjects)
                for i = 1:length(app.selectedObjects)
                    value = strcmp(app.ClassesListBox.Items,app.ClassesListBox.Value);
                    [hi, width] = size(app.classCatalog{value,3});
                    [row,col] = ind2sub([width hi],app.selectedObjects(i).CellNumber);
                    cometStruct = app.classCatalog{value,3}{col, row};
                    app.mainApp.selectedComet.className = app.ClassesListBox.Value;
                    app.mainApp.selectedComet.param = app.mainApp.comet_handles.Classes.(app.ClassesListBox.Value).Members(cometStruct.CellNumber);
                    while numel(app.mainApp.axes1.Children) > 1
                        delete(app.mainApp.axes1.Children(1))
                    end
                    app.Panel.Enable = 'off';
                    [bool, warnString] = removeComet(app.mainApp);
                    if bool == 0
                        appTextDlg(app, warnString, 'Message', 'error')
                    end
                    recalcCatalog(app, app.isStart)
                    refreshCatalogPlot(app)
                    app.Panel.Enable = 'on';
                end
                app.mainApp.selectedComet = [];
                scroll(app.ClassMembersPanel,'top')
                app.mainApp.axes1.Children.CData = composeImage(app.mainApp.comet_handles.Imgs_Stretched(:,:,:,app.mainApp.comet_handles.IndImgShown));
            end
        end

        % Button pushed function: RenameClassButton
        function RenameClassButtonPushed(app, event)
            oldClassName = app.ClassesListBox.Value;
            classNameInput(app.mainApp,app.ClassesListBox.Value);
            newClassName = setdiff(app.mainApp.pop_class.Items,app.ClassesListBox.Items);
            idx = strcmp(app.classCatalog(:,1),oldClassName);
            app.classCatalog(idx,1) = newClassName;
            app.ClassesListBox.Items = app.classCatalog(:,1);
            app.ClassesListBox.Value = newClassName;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Class_Browser = uifigure('Visible', 'off');
            app.Class_Browser.Name = 'Class Browser';
            app.Class_Browser.CloseRequestFcn = createCallbackFcn(app, @Class_BrowserCloseRequest, true);
            app.Class_Browser.WindowButtonDownFcn = createCallbackFcn(app, @Class_BrowserWindowButtonDown, true);
            app.ClassMembersPanel = uipanel(app.Class_Browser);
            app.ClassMembersPanel.Title = 'Class Members';
            app.ClassMemberAxes = uiaxes(app.ClassMembersPanel);
            app.Panel = uipanel(app.Class_Browser);
            app.Panel.Title = 'Panel';
            app.ClassesListBoxLabel = uilabel(app.Panel);
            app.ClassesListBoxLabel.Text = 'Classes';
            app.ClassesListBox = uilistbox(app.Panel);
            app.ClassesListBox.Items = {};
            app.ClassesListBox.ValueChangedFcn = createCallbackFcn(app, @ClassesListBoxValueChanged, true);
            app.ClassesListBox.Value = {};
            app.JumptoCometButton = uibutton(app.Panel, 'push');
            app.JumptoCometButton.ButtonPushedFcn = createCallbackFcn(app, @JumptoCometButtonPushed, true);
            app.JumptoCometButton.Text = 'Jump to Comet';
            app.RemoveCometsButton = uibutton(app.Panel, 'push');
            app.RemoveCometsButton.Visible = 'off';
            app.RemoveCometsButton.Text = 'Remove Comets';
            app.RenameClassButton = uibutton(app.Panel, 'push');
            app.RenameClassButton.ButtonPushedFcn = createCallbackFcn(app, @RenameClassButtonPushed, true);
            app.RenameClassButton.Text = 'Rename Class';
            app.Image = uiimage(app.Panel);
            app.RemoveCometButton = uibutton(app.Panel, 'push');
            app.RemoveCometButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCometButtonPushed, true);
            app.RemoveCometButton.Text = 'Remove Comet';
            % Show the figure after all components are created
            app.Class_Browser.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classBrowser(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Class_Browser)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Class_Browser)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Class_Browser)
        end
    end
end