classdef classificationOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        segmentationOptionsUIFigure    matlab.ui.Figure
        SelectfileButton               matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        ExportedMeasurementsEditField  matlab.ui.control.EditField
        ExportedMeasurementsEditFieldLabel  matlab.ui.control.Label
        SelectmodelButton              matlab.ui.control.Button
        PretrainedClassifierEditField  matlab.ui.control.EditField
        PretrainedClassifierEditFieldLabel  matlab.ui.control.Label
        ClassificationTrainingOptionsLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainApp
    end
    
    properties (Access = private)
        validParameters = 1 % Indicator of parameter correctness
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            app.MethodDropDown.Items = {'Decision tree','k Nearest Neighbours','Naive Bayes','Support Vector Machine'};
            if ~isempty(app.mainApp.classificationModelPath)
                app.PretrainedClassifierEditField.Value = app.mainApp.classificationModelPath;
            end
            if isempty(app.mainApp.classificationMethod)
                app.MethodDropDown.Value = app.MethodDropDown.Items(1);
            else
                app.MethodDropDown.Value = app.mainApp.classificationMethod;
            end
            if ~isempty(app.mainApp.metaDataPath)
                app.ExportedMeasurementsEditField.Value = app.mainApp.metaDataPath;
            end
        end

        % Button pushed function: SelectmodelButton
        function SelectmodelButtonPushed(app, event)
            [file, path] = uigetfile('.mat','Select pretrained model!');
            if ischar(file)
                app.PretrainedClassifierEditField.Value = fullfile(path,file);
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.mainApp.classificationMethod = app.MethodDropDown.Value;
            app.mainApp.classificationModelPath = app.PretrainedClassifierEditField.Value;
            app.mainApp.metaDataPath = app.ExportedMeasurementsEditField.Value;
            delete(app);
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file, path] = uigetfile('.csv','Select meta data file!');
            if ischar(file)
                app.ExportedMeasurementsEditField.Value = fullfile(path,file);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.segmentationOptionsUIFigure = uifigure('Visible', 'off');
            app.segmentationOptionsUIFigure.Name = 'MATLAB App';
            app.ClassificationTrainingOptionsLabel = uilabel(app.segmentationOptionsUIFigure);
            app.ClassificationTrainingOptionsLabel.Text = 'Classification - Training Options';
            app.PretrainedClassifierEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.PretrainedClassifierEditFieldLabel.Text = 'Pre-trained Classifier';
            app.PretrainedClassifierEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.PretrainedClassifierEditField.Value = '[ .mat ]';
            app.SelectmodelButton = uibutton(app.segmentationOptionsUIFigure, 'push');
            app.SelectmodelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmodelButtonPushed, true);
            app.SelectmodelButton.Text = 'Select model';
            app.ExportedMeasurementsEditFieldLabel = uilabel(app.segmentationOptionsUIFigure);
            app.ExportedMeasurementsEditFieldLabel.Text = 'Exported Measurements';
            app.ExportedMeasurementsEditField = uieditfield(app.segmentationOptionsUIFigure, 'text');
            app.ExportedMeasurementsEditField.Value = '[ .csv ]';
            app.MethodDropDownLabel = uilabel(app.segmentationOptionsUIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.segmentationOptionsUIFigure);
            app.MethodDropDown.Items = {};
            app.MethodDropDown.Value = {};
            app.ApplyButton = uibutton(app.segmentationOptionsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SelectfileButton = uibutton(app.segmentationOptionsUIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            % Show the figure after all components are created
            app.segmentationOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classificationOptions(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.segmentationOptionsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.segmentationOptionsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.segmentationOptionsUIFigure)
        end
    end
end